classdef app_designer < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        OptionsNoDelay             matlab.ui.control.DropDown
        TestButton                 matlab.ui.control.Button
        ResultsLabel               matlab.ui.control.Label
        Results                    matlab.ui.control.TextArea
        PredictionanddetectionofepilepticseizuresLabel  matlab.ui.control.Label
        OptionsDropDown            matlab.ui.control.DropDown
        OptionsDropDownLabel       matlab.ui.control.Label
        PatientDropDown            matlab.ui.control.DropDown
        PatientDropDownLabel       matlab.ui.control.Label
        Class_BalancingCheckBox    matlab.ui.control.CheckBox
        Error_WeightsCheckBox      matlab.ui.control.CheckBox
        AutoEncoderButtonGroup     matlab.ui.container.ButtonGroup
        Button_20                  matlab.ui.control.RadioButton
        Button_15                  matlab.ui.control.RadioButton
        Button_10                  matlab.ui.control.RadioButton
        Button_5                   matlab.ui.control.RadioButton
        WithoutButton              matlab.ui.control.RadioButton
        TrainandTestButton         matlab.ui.control.Button
        NeuralNetworksButtonGroup  matlab.ui.container.ButtonGroup
        NoDelayNNButton            matlab.ui.control.RadioButton
        LSTMButton                 matlab.ui.control.RadioButton
        CNNButton                  matlab.ui.control.RadioButton
    end

    properties (Access = public)
        AutoEncoder5 double
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TrainandTestButton
        function TrainButtonPushed(comp, event)

            % NN
            if comp.CNNButton.Value
                NN_name = "CNN";
            elseif comp.NoDelayNNButton.Value
                NN_name = "NoDelayMLNN";
            elseif comp.LSTMButton.Value
                NN_name = "LSTM";
            end
            
            % AutoEncoder
            if comp.Button_5.Value
                auto = 1;
                number_features = 5;
            elseif comp.Button_10.Value
                auto = 1;
                number_features = 10;
            elseif comp.Button_15.Value
                auto = 1;
                number_features = 15;
            elseif comp.Button_20.Value
                auto = 1;
                number_features = 20;
            else
                auto = 0;
                number_features = 0;
            end
           
            %Option 
            value = comp.OptionsNoDelay.Value;
            if value == "Detect"
                option = 1;
            else 
                option = 2;
            end
            train_and_test(NN_name,auto,number_features,comp.Class_BalancingCheckBox.Value,comp.Error_WeightsCheckBox.Value, option,comp.PatientDropDown.Value,comp.Results);


        end

        % Value changed function: OptionsDropDown
        function OptionsDropDownValueChanged(comp, event)
            comp.Results.Value = "";
            value = comp.OptionsDropDown.Value;
            if value == "Test"
                comp.Class_BalancingCheckBox.Visible = "off";
                comp.Error_WeightsCheckBox.Visible = "off";
                comp.TestButton.Visible = "on";
                comp.TrainandTestButton.Visible = "off";
            elseif value == "Train and Test"
                comp.Class_BalancingCheckBox.Visible = "on";
                comp.Error_WeightsCheckBox.Visible = "on";
                comp.TestButton.Visible = "off";
                comp.TrainandTestButton.Visible = "On";
            end
        end

        % Button pushed function: TestButton
        function TestButtonPushed(comp, event)
            % Encoder
            if comp.CNNButton.Value
                NN_name = "CNN";
            elseif comp.NoDelayNNButton.Value
                NN_name = "NoDelayNN";
            elseif comp.LSTMButton.Value
                NN_name = "LSTM";
            end
            % AutoEncoder
            if comp.Button_5.Value
                auto = 1;
                number_features = 5;
            elseif comp.Button_10.Value
                auto = 1;
                number_features = 10;
            elseif comp.Button_15.Value
                auto = 1;
                number_features = 15;
            elseif comp.Button_20.Value
                auto = 1;
                number_features = 20;
            else
                auto = 0;
                number_features = 0;
            end
            only_test(NN_name,auto,number_features,comp.PatientDropDown.Value,comp.Results)
        end

        % Selection changed function: NeuralNetworksButtonGroup
        function NeuralNetworksButtonGroupSelectionChanged(comp, event)
            if comp.CNNButton.Value
                comp.AutoEncoderButtonGroup.Visible = "off";
            else
                comp.AutoEncoderButtonGroup.Visible = "on";
            end    
            if comp.NoDelayNNButton.Value
                comp.OptionsNoDelay.Visible = "on";
            else
                 comp.OptionsNoDelay.Visible = "off";
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 555 489];
            comp.BackgroundColor = [0.6588 0.7294 0.851];
            comp.NeuralNetworksButtonGroup = uibuttongroup(comp);
            comp.NeuralNetworksButtonGroup.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @NeuralNetworksButtonGroupSelectionChanged, true);
            comp.NeuralNetworksButtonGroup.Title = 'Neural Networks';
            comp.CNNButton = uiradiobutton(comp.NeuralNetworksButtonGroup);
            comp.CNNButton.Text = 'CNN';
            comp.CNNButton.Value = true;
            comp.LSTMButton = uiradiobutton(comp.NeuralNetworksButtonGroup);
            comp.LSTMButton.Text = 'LSTM';
            comp.NoDelayNNButton = uiradiobutton(comp.NeuralNetworksButtonGroup);
            comp.NoDelayNNButton.Text = 'NoDelayNN';
            comp.TrainandTestButton = uibutton(comp, 'push');
            comp.TrainandTestButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @TrainButtonPushed, true);
            comp.TrainandTestButton.Icon = fullfile(pathToMLAPP, 'Logos', 'train_logo.png');
            comp.TrainandTestButton.Text = 'Train and Test';
            comp.AutoEncoderButtonGroup = uibuttongroup(comp);
            comp.AutoEncoderButtonGroup.Title = 'AutoEncoder';
            comp.AutoEncoderButtonGroup.Visible = 'off';
            comp.WithoutButton = uiradiobutton(comp.AutoEncoderButtonGroup);
            comp.WithoutButton.Text = 'Without';
            comp.WithoutButton.Value = true;
            comp.Button_5 = uiradiobutton(comp.AutoEncoderButtonGroup);
            comp.Button_5.Text = '5';
            comp.Button_10 = uiradiobutton(comp.AutoEncoderButtonGroup);
            comp.Button_10.Text = '10';
            comp.Button_15 = uiradiobutton(comp.AutoEncoderButtonGroup);
            comp.Button_15.Text = '15';
            comp.Button_20 = uiradiobutton(comp.AutoEncoderButtonGroup);
            comp.Button_20.Text = '20';
            comp.Error_WeightsCheckBox = uicheckbox(comp);
            comp.Error_WeightsCheckBox.Text = 'Error_Weights';
            comp.Class_BalancingCheckBox = uicheckbox(comp);
            comp.Class_BalancingCheckBox.Text = 'Class_Balancing';
            comp.PatientDropDownLabel = uilabel(comp);
            comp.PatientDropDownLabel.Text = 'Patient';
            comp.PatientDropDown = uidropdown(comp);
            comp.PatientDropDown.Items = {'112502', '54802'};
            comp.PatientDropDown.Value = '112502';
            comp.OptionsDropDownLabel = uilabel(comp);
            comp.OptionsDropDownLabel.Text = 'Options';
            comp.OptionsDropDown = uidropdown(comp);
            comp.OptionsDropDown.Items = {'Train and Test', 'Test'};
            comp.OptionsDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @OptionsDropDownValueChanged, true);
            comp.OptionsDropDown.Value = 'Train and Test';
            comp.PredictionanddetectionofepilepticseizuresLabel = uilabel(comp);
            comp.PredictionanddetectionofepilepticseizuresLabel.Text = 'Prediction and detection of epileptic seizures. ';
            comp.Results = uitextarea(comp);
            comp.Results.Editable = 'off';
            comp.Results.Placeholder = 'sensitivity/specificity';
            comp.ResultsLabel = uilabel(comp);
            comp.ResultsLabel.Text = 'Results:';
            comp.TestButton = uibutton(comp, 'push');
            comp.TestButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @TestButtonPushed, true);
            comp.TestButton.Icon = fullfile(pathToMLAPP, 'Logos', 'test_logo.png');
            comp.TestButton.Visible = 'off';
            comp.TestButton.Text = '    Test';
            comp.OptionsNoDelay = uidropdown(comp);
            comp.OptionsNoDelay.Items = {'Detect', 'Predict'};
            comp.OptionsNoDelay.Visible = 'off';
            comp.OptionsNoDelay.Value = 'Detect';
        end
    end
end